classdef R2RContinuousProcessSimulator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        temp                            matlab.ui.control.NumericEditField
        Panel_3                         matlab.ui.container.Panel
        RegisterErrorPlot               matlab.ui.control.UIAxes
        TensionTransferPlot             matlab.ui.control.UIAxes
        SimulateButton                  matlab.ui.control.Button
        SiM                             matlab.ui.control.Image
        KU                              matlab.ui.control.Image
        Panel                           matlab.ui.container.Panel
        OpTension                       matlab.ui.control.NumericEditField
        FixedStepsecLabel_6             matlab.ui.control.Label
        WebSpeed                        matlab.ui.control.NumericEditField
        FixedStepsecLabel_5             matlab.ui.control.Label
        WebThickness                    matlab.ui.control.NumericEditField
        FixedStepsecLabel_4             matlab.ui.control.Label
        WebWidth                        matlab.ui.control.NumericEditField
        FixedStepsecLabel_3             matlab.ui.control.Label
        StopTime                        matlab.ui.control.NumericEditField
        FixedStepsecLabel_2             matlab.ui.control.Label
        TheNumberofSpansEditField       matlab.ui.control.NumericEditField
        TheNumberofSpansEditFieldLabel  matlab.ui.control.Label
        TheNumberofSpansSlider          matlab.ui.control.Slider
        TabGroup                        matlab.ui.container.TabGroup
        Span                            matlab.ui.container.Tab
        MagnitudeEditField              matlab.ui.control.NumericEditField
        MagnitudeEditFieldLabel         matlab.ui.control.Label
        EndTimesecEditField             matlab.ui.control.NumericEditField
        EndTimesecEditFieldLabel        matlab.ui.control.Label
        StartTimesecEditField           matlab.ui.control.NumericEditField
        StartTimesecEditFieldLabel      matlab.ui.control.Label
        Switch                          matlab.ui.control.RockerSwitch
        DisturbanceTypeDropDown         matlab.ui.control.DropDown
        DisturbanceTypeDropDownLabel    matlab.ui.control.Label
        SpanLengthmEditField            matlab.ui.control.NumericEditField
        SpanLengthmEditFieldLabel       matlab.ui.control.Label
        ThermalExpansionCoefficient1KEditField  matlab.ui.control.NumericEditField
        ThermalExpansionCoefficient1KEditFieldLabel  matlab.ui.control.Label
        EquivalentYoungsModulusGPaEditField  matlab.ui.control.NumericEditField
        EquivalentYoungsModulusGPaEditFieldLabel  matlab.ui.control.Label
        InletYoungsModulusGPaEditField  matlab.ui.control.NumericEditField
        InletYoungsModulusGPaEditFieldLabel  matlab.ui.control.Label
        SpanInfo                        matlab.ui.control.Image
        Panel_2                         matlab.ui.container.Panel
        TensionDisturbancePlot          matlab.ui.control.UIAxes
        VelocityDisturbancePlot         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        simulModel; % Description
        vd;
        td;
    end
    
    methods (Access = private)
        
        function [] = editSpan(app, spanLength)
            app.SpanInfo.ImageSource = append(string(spanLength),'.png');
            for i = 1:length(app.Span)
                delete(app.Span(i))
%                 length(app.Span)
%                 eval(append('delete(app.Span_', string(i), ')'))
            end
            editTemp(app, spanLength)

            for i = 1:spanLength
                app.Span(i) = uitab(app.TabGroup);
                app.Span(i).Title = append('Span ', string(i));
                
                app.InletYoungsModulusGPaEditFieldLabel(i) = uilabel(app.Span(i));
                app.InletYoungsModulusGPaEditFieldLabel(i).Position = [15 213 208 22];
                app.InletYoungsModulusGPaEditFieldLabel(i).Text = 'Inlet Young''s Modulus [GPa]';
                
                app.InletYoungsModulusGPaEditField(i) = uieditfield(app.Span(i), 'numeric');
                app.InletYoungsModulusGPaEditField(i).Position = [239 213 100 22];
                app.InletYoungsModulusGPaEditField(i).Value = 1.883;
                
                app.EquivalentYoungsModulusGPaEditFieldLabel(i) = uilabel(app.Span(i));
                app.EquivalentYoungsModulusGPaEditFieldLabel(i).Position = [15 183 209 22];
                app.EquivalentYoungsModulusGPaEditFieldLabel(i).Text = 'Equivalent Young''s Modulus [GPa]';
                
                app.EquivalentYoungsModulusGPaEditField(i) = uieditfield(app.Span(i), 'numeric');
                app.EquivalentYoungsModulusGPaEditField(i).Position = [239 183 100 22];
                app.EquivalentYoungsModulusGPaEditField(i).Value = 1.883;

                app.ThermalExpansionCoefficient1KEditFieldLabel(i) = uilabel(app.Span(i));
                app.ThermalExpansionCoefficient1KEditFieldLabel(i).Position = [15 153 209 22];
                app.ThermalExpansionCoefficient1KEditFieldLabel(i).Text = 'Thermal Expansion Coefficient [1/K]';
                
                app.ThermalExpansionCoefficient1KEditField(i) = uieditfield(app.Span(i), 'numeric');
                app.ThermalExpansionCoefficient1KEditField(i).Position = [239 153 100 22];
                app.ThermalExpansionCoefficient1KEditField(i).Value = 0;
                
                app.SpanLengthmEditFieldLabel(i) = uilabel(app.Span(i));
                app.SpanLengthmEditFieldLabel(i).Position = [15 243 209 22];
                app.SpanLengthmEditFieldLabel(i).Text = 'Span Length [m]';
                
                app.SpanLengthmEditField(i) = uieditfield(app.Span(i), 'numeric');
                app.SpanLengthmEditField(i).Position = [239 243 100 22];
                app.SpanLengthmEditField(i).Value = 3;
                
                app.DisturbanceTypeDropDownLabel(i) = uilabel(app.Span(i));
                app.DisturbanceTypeDropDownLabel(i).Position = [15 95 194 22];
                app.DisturbanceTypeDropDownLabel(i).Text = 'Disturbance Type';

                app.DisturbanceTypeDropDown(i) = uidropdown(app.Span(i));
                app.DisturbanceTypeDropDown(i).Items = {'Step', 'Impulse', 'Ramp', 'Sine'};
                app.DisturbanceTypeDropDown(i).ValueChangedFcn = createCallbackFcn(app, @DisturbanceTypeDropDownValueChanged, true);
                app.DisturbanceTypeDropDown(i).Position = [240 95 99 22];
                app.DisturbanceTypeDropDown(i).Value = 'Step';

                app.Switch(i) = uiswitch(app.Span(i), 'rocker');
                app.Switch(i).Items = {'Velocity Disturbance [m/s]', 'Tension Disturbance [N]'};
                app.Switch(i).Orientation = 'horizontal';
                app.Switch(i).Position = [159 125 45 20];
                app.Switch(i).Value = 'Velocity Disturbance [m/s]';
    
                % Create StratTimesecEditFieldLabel
                app.StartTimesecEditFieldLabel(i) = uilabel(app.Span(i));
                app.StartTimesecEditFieldLabel(i).Position = [15 66 209 22];
                app.StartTimesecEditFieldLabel(i).Text = 'Strat Time [sec]';
    
                % Create StratTimesecEditField
                app.StartTimesecEditField(i) = uieditfield(app.Span(i), 'numeric');
                app.StartTimesecEditField(i).Position = [239 66 100 22];
    
                % Create EndTimesecEditFieldLabel
                app.EndTimesecEditFieldLabel(i) = uilabel(app.Span(i));
                app.EndTimesecEditFieldLabel(i).Position = [15 37 209 22];
                app.EndTimesecEditFieldLabel(i).Text = 'End Time [sec]';
    
                % Create EndTimesecEditField
                app.EndTimesecEditField(i) = uieditfield(app.Span(i), 'numeric');
                app.EndTimesecEditField(i).Position = [239 37 100 22];
    
                % Create MagnitudeEditFieldLabel
                app.MagnitudeEditFieldLabel(i) = uilabel(app.Span(i));
                app.MagnitudeEditFieldLabel(i).Position = [15 8 209 22];
                app.MagnitudeEditFieldLabel(i).Text = 'Magnitude';
    
                % Create MagnitudeEditField
                app.MagnitudeEditField(i) = uieditfield(app.Span(i), 'numeric');
                app.MagnitudeEditField(i).Position = [239 8 100 22];
                if i > 1
                    app.Switch(i).Enable = 0;
                end
            end
            DisturbanceTypeDropDownValueChanged(app);
        end
        
        function results = simulateFucn(app)
            f = waitbar(0, 'Calculating...','Name','Progress Bar');
            for i = 1:app.TheNumberofSpansEditField.Value
                E1eq = app.EquivalentYoungsModulusGPaEditField(i).Value * 10^9;
                E1t = app.InletYoungsModulusGPaEditField(i).Value * 10^9;
                ep1eq = (app.ThermalExpansionCoefficient1KEditField(i).Value) * (app.temp(i + 1).Value - app.temp(i).Value);
                t = app.WebThickness.Value / 10^6;
                w = app.WebWidth.Value / 1000;
                A = t*w;
                L1 = app.SpanLengthmEditField(i).Value;
                v = app.WebSpeed.Value;
                v00 = v;
                v10 = v;
                tau1 = L1 / v10;
                FT = 0.1;%app.FixedStep.Value;
                ST = app.StopTime.Value;
                set_param(app.simulModel, 'Solver', 'ode4', 'FixedStep', string(FT), 'StopTime', string(ST))

                simTime = 0:FT:ST;

                if app.Switch(i).Value == "Velocity Disturbance [m/s]"
                    if i == 1
                        T0 = [simTime ; simTime * 0]';
                        app.td{i} = T0;
                    else
                        T0 = [simTime ; results{i-1}.T1' + simTime * 0]';
                        app.td{i} = [simTime ; simTime * 0]';
                    end

                    V0 = [simTime ; makeDisturbance(app, app.DisturbanceTypeDropDown(i).Value, simTime, FT, i)]';
                    app.vd{i} = V0;

                    if i == app.TheNumberofSpansEditField.Value
                        V1 = [simTime ; simTime * 0]';
                        app.vd{i+1} = V1;
                    elseif app.DisturbanceTypeDropDown(i+1).Value == "Tension Disturbance [N]"
                        V1 = [simTime ; simTime * 0]';
                        app.vd{i+1} = V1;
                    else
                        V1 = [simTime ; makeDisturbance(app, app.DisturbanceTypeDropDown(i+1).Value, simTime, FT, i + 1)]';
                        app.vd{i+1} = V1;
                    end
                elseif app.Switch(i).Value == "Tension Disturbance [N]"
                    if i == 1
                        T0 = [simTime ; makeDisturbance(app, app.DisturbanceTypeDropDown(i).Value, simTime, FT, i)]';
                        app.td{i} = T0;
                    else
                        T0 = [simTime ; results{i-1}.T1' + makeDisturbance(app, app.DisturbanceTypeDropDown(i).Value, simTime, FT, i)]';
                        app.td{i} = [simTime ; makeDisturbance(app, app.DisturbanceTypeDropDown(i).Value, simTime, FT, i)]';
                    end

                    V0 = [simTime ; simTime * 0]';
                    app.vd{i} = V0;

                    if i == app.TheNumberofSpansEditField.Value
                        V1 = [simTime ; simTime * 0]';
                        app.vd{i+1} = V1;
                    elseif app.DisturbanceTypeDropDown(i+1).Value == "Tension Disturbnace [N]"
                        V1 = [simTime ; simTime * 0];
                        app.vd{i+1} = V1;
                    else
                        V1 = [simTime ; makeDisturbance(app, app.DisturbanceTypeDropDown(i+1).Value, simTime, FT, i + 1)]';
                        app.vd{i+1} = V1;
                    end
                end

                assignin('base', 'E1eq', E1eq)
                assignin('base', 'E1t', E1t)
                assignin('base', 'ep1eq', ep1eq)
                assignin('base', 'A', A)
                assignin('base', 'L1', L1)
                assignin('base', 'v00', v00)
                assignin('base', 'v10', v10)
                assignin('base', 'tau1', tau1)
                assignin('base', 'T0', T0)
                assignin('base', 'V0', V0)
                assignin('base', 'V1', V1)
                results{i} = sim("Thermal_Tension_Transfer_Register.slx");
                waitbar(i/app.TheNumberofSpansEditField.Value, f);
            end
            close(f);
        end
        
        function results = makeDisturbance(app, Type, simTime, FT, num)
            if Type == "Step"
                results = simTime * 0;
                results(app.StartTimesecEditField(num).Value/FT+1:end) = app.EndTimesecEditField(num).Value;
            elseif Type == "Impulse"
                results = simTime * 0;
                results(app.StartTimesecEditField(num).Value/FT+1:app.EndTimesecEditField(num).Value/FT) = app.MagnitudeEditField(num).Value;
            elseif Type == "Ramp"
                results = simTime * app.EndTimesecEditField(num).Value;
                results(app.StartTimesecEditField(num).Value/FT+1:end) = results(app.StartTimesecEditField(num).Value/FT+1);
            elseif Type == "Sine"
                results = app.StartTimesecEditField(num).Value * sin(2*pi*app.EndTimesecEditField(num).Value*simTime);
            end
        end
        
        function [] = editTemp(app, len)
            for i = 1:length(app.temp)
                delete(app.temp(i))
            end
            for i = 1:len + 1
                app.temp(i) = uieditfield(app.UIFigure, 'numeric');
                app.temp(i).HorizontalAlignment = 'center';
                app.temp(i).FontSize = 10;
                app.temp(i).Position = [569 + 131 * (i - 1) 1280 34 15];
                app.temp(i).Value = 25;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.simulModel = load_system("Thermal_Tension_Transfer_Register.slx");
            DisturbanceTypeDropDownValueChanged(app);
            editTemp(app, 1)
        end

        % Value changed function: TheNumberofSpansEditField
        function TheNumberofSpansEditFieldValueChanged(app, event)
            value = app.TheNumberofSpansEditField.Value;
            app.TheNumberofSpansSlider.Value = value;
            editSpan(app, value);
        end

        % Value changed function: TheNumberofSpansSlider
        function TheNumberofSpansSliderValueChanged(app, event)
            value = app.TheNumberofSpansSlider.Value;
            app.TheNumberofSpansEditField.Value = round(value);
            app.TheNumberofSpansSlider.Value = round(value);
            editSpan(app, round(value));
        end

        % Button pushed function: SimulateButton
        function SimulateButtonPushed(app, event)
            app.SimulateButton.Enable = 0;
            app.SimulateButton.Text = "In simulation ...";
            app.SimulateButton.FontSize = 18;
            app.SimulateButton.FontColor = "White";
            app.SimulateButton.BackgroundColor = "Red";
            pause(0.0001);
            Results = simulateFucn(app);
            
            C = ["#000000", "#FF0000", "#0000FF", "#FF00FF", "#00FF00", "#0072BD", "#D95319", "#EDB120", "#7E2F8E", "#77AC30", "#4DBEEE", "#A2142F"];
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            hold(app.TensionDisturbancePlot, 'off')
            plot(app.TensionDisturbancePlot, NaN, NaN)
            
            tmpm = 0;
            tmpM = 0;

            for i = 1:app.TheNumberofSpansEditField.Value
                plot(app.TensionDisturbancePlot, app.td{i}(:,1), app.td{i}(:,2), 'Color', C(i), 'LineWidth', 2)
                if i == 1
                    hold(app.TensionDisturbancePlot, 'on')
                end
                tmpm = min(min(app.td{i}(:,2)), tmpm);
                tmpM = max(max(app.td{i}(:,2)), tmpM);
                leg(i) = append("Span ", string(i));
            end
            lgd = legend(app.TensionDisturbancePlot, leg);
            if 7 < app.TheNumberofSpansEditField.Value
                lgd.NumColumns = 2;
            end
            gap = tmpM - tmpm;
            if tmpM == tmpm
                ylim(app.TensionDisturbancePlot, [-1 1])
            else
                ylim(app.TensionDisturbancePlot, [tmpm - gap / 2 tmpM + gap / 2])
            end
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            hold(app.VelocityDisturbancePlot, 'off')
            plot(app.VelocityDisturbancePlot, NaN, NaN)

            tmpm = 0;
            tmpM = 0;

            for i = 1:app.TheNumberofSpansEditField.Value
                plot(app.VelocityDisturbancePlot, app.vd{i}(:,1), app.vd{i}(:,2), 'Color', C(i), 'LineWidth', 2)
                if i == 1
                    hold(app.VelocityDisturbancePlot, 'on')
                end
                tmpm = min(min(app.vd{i}(:,2)), tmpm);
                tmpM = max(max(app.vd{i}(:,2)), tmpM);
                leg(i) = append("Span ", string(i));
            end
            lgd = legend(app.VelocityDisturbancePlot, leg);
            if 7 < app.TheNumberofSpansEditField.Value
                lgd.NumColumns = 2;
            end
            gap = tmpM - tmpm;
            if tmpM == tmpm
                ylim(app.VelocityDisturbancePlot, [-1 1])
            else
                ylim(app.VelocityDisturbancePlot, [tmpm - gap / 2 tmpM + gap / 2])
            end
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            hold(app.TensionTransferPlot, 'off')
            plot(app.TensionTransferPlot, NaN, NaN)
            
            tmpm = 0;
            tmpM = 0;
            
            for i = 1:app.TheNumberofSpansEditField.Value
                plot(app.TensionTransferPlot, Results{i}.Time, app.OpTension.Value + Results{i}.T1, 'Color', C(i), 'LineWidth', 2)
                if i == 1
                    hold(app.TensionTransferPlot, 'on')
                end
                tmpm = min(min(app.OpTension.Value + Results{i}.T1), tmpm);
                tmpM = max(max(app.OpTension.Value + Results{i}.T1), tmpM);
                leg(i) = append("Span ", string(i));
            end
            lgd = legend(app.TensionTransferPlot, leg);
            if 7 < app.TheNumberofSpansEditField.Value
                lgd.NumColumns = 2;
            end
%             gap = tmpM - tmpm;
%             if tmpM == tmpm
%                 ylim(app.TensionTransferPlot, [-1 1])
%             else
%                 ylim(app.TensionTransferPlot, [tmpm - gap / 2 tmpM + gap / 2])
%             end
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            hold(app.RegisterErrorPlot, 'off')
            plot(app.RegisterErrorPlot, NaN, NaN)
            
            tmpm = 0;
            tmpM = 0;
            
            if app.TheNumberofSpansEditField.Value > 1
                for i = 2:app.TheNumberofSpansEditField.Value
                    plot(app.RegisterErrorPlot, Results{i}.Time, Results{i}.R1, 'Color', C(i), 'LineWidth', 2)
                    if i == 2
                        hold(app.RegisterErrorPlot, 'on')
                    end
                    legR(i-1) = append("Span ", string(i));
                end
            end

            if app.TheNumberofSpansEditField.Value > 1
                lgd = legend(app.RegisterErrorPlot, legR);
                if 7 < app.TheNumberofSpansEditField.Value
                    lgd.NumColumns = 2;
                end
            else
                legend(app.RegisterErrorPlot, 'off')
            end
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            app.SimulateButton.Enable = 1;
            app.SimulateButton.Text = "Simulate";
            app.SimulateButton.FontSize = 30;
            app.SimulateButton.FontColor = "Black";
            app.SimulateButton.BackgroundColor = [0.94,0.94,0.94];
        end

        % Value changed function: DisturbanceTypeDropDown
        function DisturbanceTypeDropDownValueChanged(app, event)
            for i = 1:app.TheNumberofSpansEditField.Value
                value = app.DisturbanceTypeDropDown(i).Value;
                if value == "Step"
                    app.StartTimesecEditFieldLabel(i).Text = "Start Time [sec]";
                    app.StartTimesecEditField(i).Enable = 1;
                    app.EndTimesecEditFieldLabel(i).Text = "Magnitude";
                    app.EndTimesecEditField(i).Enable = 1;
                    app.MagnitudeEditFieldLabel(i).Text = "";
                    app.MagnitudeEditField(i).Enable = 0;
                elseif value == "Impulse"
                    app.StartTimesecEditFieldLabel(i).Text = "Start Time [sec]";
                    app.StartTimesecEditField(i).Enable = 1;
                    app.EndTimesecEditFieldLabel(i).Text = "End Time [sec]";
                    app.EndTimesecEditField(i).Enable = 1;
                    app.MagnitudeEditFieldLabel(i).Text = "Magnitude";
                    app.MagnitudeEditField(i).Enable = 1;
                elseif value == "Ramp"
                    app.StartTimesecEditFieldLabel(i).Text = "End Time [sec]";
                    app.StartTimesecEditField(i).Enable = 1;
                    app.EndTimesecEditFieldLabel(i).Text = "Gradient";
                    app.EndTimesecEditField(i).Enable = 1;
                    app.MagnitudeEditFieldLabel(i).Text = "";
                    app.MagnitudeEditField(i).Enable = 0;
                elseif value == "Sine"
                    app.StartTimesecEditFieldLabel(i).Text = "Amplitude";
                    app.StartTimesecEditField(i).Enable = 1;
                    app.EndTimesecEditFieldLabel(i).Text = "Frequency";
                    app.EndTimesecEditField(i).Enable = 1;
                    app.MagnitudeEditFieldLabel(i).Text = "";
                    app.MagnitudeEditField(i).Enable = 0;
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel_2 = uipanel(app.UIFigure);
            app.VelocityDisturbancePlot = uiaxes(app.Panel_2);
            title(app.VelocityDisturbancePlot, 'Velocity Disturbance')
            xlabel(app.VelocityDisturbancePlot, 'Time [sec]')
            ylabel(app.VelocityDisturbancePlot, 'Web Speed Variation [m/s]')
            zlabel(app.VelocityDisturbancePlot, 'Z')
            app.TensionDisturbancePlot = uiaxes(app.Panel_2);
            title(app.TensionDisturbancePlot, 'Tension Disturbance')
            xlabel(app.TensionDisturbancePlot, 'Time [sec]')
            ylabel(app.TensionDisturbancePlot, 'Tension Variation [N]')
            zlabel(app.TensionDisturbancePlot, 'Z')
            app.SpanInfo = uiimage(app.UIFigure);
            app.SpanInfo.ImageSource = '1.png';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Span = uitab(app.TabGroup);
            app.Span.Title = 'Span1';
            app.InletYoungsModulusGPaEditFieldLabel = uilabel(app.Span);
            app.InletYoungsModulusGPaEditFieldLabel.Text = 'Inlet Young''s Modulus [GPa]';
            app.InletYoungsModulusGPaEditField = uieditfield(app.Span, 'numeric');
            app.InletYoungsModulusGPaEditField.Value = 1.883;
            app.EquivalentYoungsModulusGPaEditFieldLabel = uilabel(app.Span);
            app.EquivalentYoungsModulusGPaEditFieldLabel.Text = 'Equivalent Young''s Modulus [GPa]';
            app.EquivalentYoungsModulusGPaEditField = uieditfield(app.Span, 'numeric');
            app.EquivalentYoungsModulusGPaEditField.Value = 1.883;
            app.ThermalExpansionCoefficient1KEditFieldLabel = uilabel(app.Span);
            app.ThermalExpansionCoefficient1KEditFieldLabel.Text = 'Thermal Expansion Coefficient [1/K]';
            app.ThermalExpansionCoefficient1KEditField = uieditfield(app.Span, 'numeric');
            app.SpanLengthmEditFieldLabel = uilabel(app.Span);
            app.SpanLengthmEditFieldLabel.Text = 'Span Length [m]';
            app.SpanLengthmEditField = uieditfield(app.Span, 'numeric');
            app.SpanLengthmEditField.Value = 3;
            app.DisturbanceTypeDropDownLabel = uilabel(app.Span);
            app.DisturbanceTypeDropDownLabel.Text = 'Disturbance Type';
            app.DisturbanceTypeDropDown = uidropdown(app.Span);
            app.DisturbanceTypeDropDown.Items = {'Step', 'Impulse', 'Ramp', 'Sine'};
            app.DisturbanceTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @DisturbanceTypeDropDownValueChanged, true);
            app.DisturbanceTypeDropDown.Value = 'Step';
            app.Switch = uiswitch(app.Span, 'rocker');
            app.Switch.Items = {'Velocity Disturbance [m/s]', 'Tension Disturbance [N]'};
            app.Switch.Value = 'Velocity Disturbance [m/s]';
            app.StartTimesecEditFieldLabel = uilabel(app.Span);
            app.StartTimesecEditFieldLabel.Text = 'Start Time [sec]';
            app.StartTimesecEditField = uieditfield(app.Span, 'numeric');
            app.EndTimesecEditFieldLabel = uilabel(app.Span);
            app.EndTimesecEditFieldLabel.Text = 'End Time [sec]';
            app.EndTimesecEditField = uieditfield(app.Span, 'numeric');
            app.MagnitudeEditFieldLabel = uilabel(app.Span);
            app.MagnitudeEditFieldLabel.Text = 'Magnitude';
            app.MagnitudeEditField = uieditfield(app.Span, 'numeric');
            app.Panel = uipanel(app.UIFigure);
            app.TheNumberofSpansSlider = uislider(app.Panel);
            app.TheNumberofSpansSlider.Limits = [1 12];
            app.TheNumberofSpansSlider.MajorTicks = [1 4 7 10 12];
            app.TheNumberofSpansSlider.MajorTickLabels = {'1', '4', '7', '10', '12'};
            app.TheNumberofSpansSlider.ValueChangedFcn = createCallbackFcn(app, @TheNumberofSpansSliderValueChanged, true);
            app.TheNumberofSpansSlider.MinorTicks = [1 2 3 4 5 6 7 8 9 10 11 12];
            app.TheNumberofSpansSlider.Value = 1;
            app.TheNumberofSpansEditFieldLabel = uilabel(app.Panel);
            app.TheNumberofSpansEditFieldLabel.Text = 'The Number of Spans';
            app.TheNumberofSpansEditField = uieditfield(app.Panel, 'numeric');
            app.TheNumberofSpansEditField.Limits = [1 12];
            app.TheNumberofSpansEditField.ValueChangedFcn = createCallbackFcn(app, @TheNumberofSpansEditFieldValueChanged, true);
            app.TheNumberofSpansEditField.Value = 1;
            app.FixedStepsecLabel_2 = uilabel(app.Panel);
            app.FixedStepsecLabel_2.Text = 'Stop Time [sec]';
            app.StopTime = uieditfield(app.Panel, 'numeric');
            app.StopTime.Value = 1000;
            app.FixedStepsecLabel_3 = uilabel(app.Panel);
            app.FixedStepsecLabel_3.Text = 'Web Width [mm]';
            app.WebWidth = uieditfield(app.Panel, 'numeric');
            app.WebWidth.Value = 150;
            app.FixedStepsecLabel_4 = uilabel(app.Panel);
            app.FixedStepsecLabel_4.Text = 'Web Thickness [um]';
            app.WebThickness = uieditfield(app.Panel, 'numeric');
            app.WebThickness.Value = 100;
            app.FixedStepsecLabel_5 = uilabel(app.Panel);
            app.FixedStepsecLabel_5.Text = 'Web Speed [m/s]';
            app.WebSpeed = uieditfield(app.Panel, 'numeric');
            app.WebSpeed.Value = 0.016667;
            app.FixedStepsecLabel_6 = uilabel(app.Panel);
            app.FixedStepsecLabel_6.Text = 'Op. Tension [N]';
            app.OpTension = uieditfield(app.Panel, 'numeric');
            app.OpTension.Value = 26.487;
            app.KU = uiimage(app.UIFigure);
            app.KU.ImageSource = 'KU.jpg';
            app.SiM = uiimage(app.UIFigure);
            app.SiM.ImageSource = 'SiM.png';
            app.SimulateButton = uibutton(app.UIFigure, 'push');
            app.SimulateButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateButtonPushed, true);
            app.SimulateButton.Text = 'Simulate';
            app.Panel_3 = uipanel(app.UIFigure);
            app.TensionTransferPlot = uiaxes(app.Panel_3);
            title(app.TensionTransferPlot, 'Variation in tension')
            xlabel(app.TensionTransferPlot, 'Time [sec]')
            ylabel(app.TensionTransferPlot, 'Tension [N]')
            zlabel(app.TensionTransferPlot, 'Z')
            app.RegisterErrorPlot = uiaxes(app.Panel_3);
            title(app.RegisterErrorPlot, 'MD Register Error')
            xlabel(app.RegisterErrorPlot, 'Time [sec]')
            ylabel(app.RegisterErrorPlot, 'MD Register Error [m]')
            zlabel(app.RegisterErrorPlot, 'Z')
            app.temp = uieditfield(app.UIFigure, 'numeric');
            app.temp.Value = 25;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = R2RContinuousProcessSimulator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end